classdef Covid_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        CountryListBoxLabel       matlab.ui.control.Label
        CountryListBox            matlab.ui.control.ListBox
        StateListBoxLabel         matlab.ui.control.Label
        StateListBox              matlab.ui.control.ListBox
        AverageofDaysSliderLabel  matlab.ui.control.Label
        AverageofDaysSlider       matlab.ui.control.Slider
        DatatoPlotButtonGroup     matlab.ui.container.ButtonGroup
        CasesButton               matlab.ui.control.RadioButton
        DeathsButton              matlab.ui.control.RadioButton
        BothButton                matlab.ui.control.RadioButton
        OptionButtonGroup         matlab.ui.container.ButtonGroup
        CumulativeButton          matlab.ui.control.RadioButton
        DailyButton               matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
         xData
       Cases_Deaths_Matrix
       CasesVec
       DeathsVec
       AverageCases
       AverageDeaths
       AvergingWindow
       Int_title
       tav
    end
    
    methods (Access = private)
        
        function PlotData(app)
             app.UIAxes.reset;
            ta=int8('Cumulative Number of Cases in');
            tb=int8('Cumulative Number of Deaths in');
            tc=int8('Cumulative Number of Cases and Deaths in');
            td=int8('Daily Number of Cases in');
            te=int8('Daily Number of Deaths in');
            tf=int8('Daily Number of Cases and Deaths in');
            if app.CasesButton.Value && app.CumulativeButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                bar(app.UIAxes,app.xData,app.AverageCases,'g');
                num_title=[ta app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.CasesButton.Value&&app.DailyButton.Value
                app.DailyVec;
                app.ComputeAverages;
                bar(app.UIAxes,app.xData,app.AverageCases,'b');
                num_title=[td app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.DeathsButton.Value&&app.CumulativeButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                plot(app.UIAxes,app.xData,app.AverageDeaths,'r');
                num_title=[tb app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.DeathsButton.Value&&app.DailyButton.Value
                app.DailyVec;
                app.ComputeAverages;
                plot(app.UIAxes,app.xData,app.AverageDeaths,'r');
                num_title=[te app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.BothButton.Value && app.CumulativeButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.xData,app.AverageCases,'g');app.UIAxes.YColor=[0.0 0.0 1.0];
                app.UIAxes.YLimMode = 'auto'; app.UIAxes.YTickMode = 'auto';
                set(app.UIAxes,'YTickLabel',num2str (get(app.UIAxes,'YTick').','%f'));
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.xData,app.AverageDeaths,'r');app.UIAxes.YColor=[1.0 0.0 0.0];
                num_title=[tc app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");
            elseif app.BothButton.Value && app.DailyButton.Value
                app.DailyVec;
                app.ComputeAverages;
                yyaxis(app.UIAxes,'left');
                bar(app.UIAxes,app.xData,app.AverageCases,'b');app.UIAxes.YColor=[0.0 0.0 1.0];
                app.UIAxes.YLimMode = 'auto'; app.UIAxes.YTickMode = 'auto';
                set(app.UIAxes,'YTickLabel',num2str (get(app.UIAxes,'YTick').','%f'));
                hold(app.UIAxes,'on');
                yyaxis(app.UIAxes,'right');
                plot(app.UIAxes,app.xData,app.AverageDeaths,'r');app.UIAxes.YColor=[1.0 0.0 0.0];
                num_title=[tf app.Int_title app.tav];
                title(app.UIAxes,char(num_title),"HorizontalAlignment","center");   
            end
            xlim(app.UIAxes,[app.xData(1),app.xData(end)]);xlim(app.UIAxes,'manual');
            app.UIAxes.YLimMode = 'auto'; app.UIAxes.YTickMode = 'auto';
            grid(app.UIAxes,'on');
            datetick(app.UIAxes,'x','mm/dd',"keeplimits");
            set(app.UIAxes,'YTickLabel',num2str(get(app.UIAxes,'YTick').','%f'));
            hold(app.UIAxes,'off');   
            
        end
        function ComputeAverages(app)
            app.AverageCases=movmean(app.CasesVec,app.AvergingWindow);
            app.AverageDeaths=movmean(app.DeathsVec,app.AvergingWindow);
        end
        function DailyVec(app)
             app.CumulativeVec;
            x=app.CasesVec - circshift(app.CasesVec,1);
            x(1)=app.CasesVec(1);
            k=logical(x<0);
            x(k)=0;
            app.CasesVec=x;
            y=app.DeathsVec - circshift(app.DeathsVec,1);
            y(1)=app.DeathsVec(1);
            g=logical(y<0);
            y(g)=0;
            app.DeathsVec=y;     
        end
        function CumulativeVec(app)
            Item_Selected=Data(app.CountryListBox.Value);
            [~, in2]= ismember(app.StateListBox.Value, app.StateListBox.Items);
            [~, in1]= ismember(app.CountryListBox.Value, app.CountryListBox.Items);
            if in1==1
                app.Cases_Deaths_Matrix=Item_Selected.global_Cases_And_Deaths;
                app.CasesVec=app.Cases_Deaths_Matrix(1,:);
                app.DeathsVec=app.Cases_Deaths_Matrix(2,:);
            else
                app.CasesVec=Item_Selected.Cases_Vector(Item_Selected.State_Index(in2));
                app.DeathsVec=Item_Selected.Deaths_Vector(Item_Selected.State_Index(in2));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             globalData=Data('Global');
            app.CountryListBox.Items=(unique(globalData.Country,'Stable'));
            app.StateListBox.Items=(unique(globalData.State,'Stable'));
            app.Cases_Deaths_Matrix=globalData.global_Cases_And_Deaths;
            app.CasesVec=app.Cases_Deaths_Matrix(1,:);
            app.DeathsVec=app.Cases_Deaths_Matrix(2,:);
            startDate= datenum(globalData.Date{1});
            endDate = datenum(globalData.Date{end});
            app.xData = linspace(startDate,endDate,length(globalData.Date));
            app.AvergingWindow=app.AverageofDaysSlider.Value;
            app.StateListBoxValueChanged;
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
           Item_Selected =Data(app.CountryListBox.Value);
           app.StateListBox.Items=(unique(Item_Selected.State,'stable'));
           app.StateListBoxValueChanged;
            
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            [~, in2]= ismember(app.StateListBox.Value, app.StateListBox.Items);
            if in2==1
                app.Int_title=app.CountryListBox.Value;
            else
                app.Int_title=app.StateListBox.Value;
            end
            app.PlotData;
            
        end

        % Selection changed function: DatatoPlotButtonGroup
        function DatatoPlotButtonGroupSelectionChanged(app, event)
            app.PlotData;  
            
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.PlotData;  
            
        end

        % Value changing function: AverageofDaysSlider
        function AverageofDaysSliderValueChanging(app, event)
            app.AvergingWindow = event.Value;
            if app.AvergingWindow~=1
                tav1=int8('('); tav2=int8('~day mean)');
                app.tav=[tav1,int8(char(string(round(app.AvergingWindow)))),tav2];
            else
                app.tav=int8('');
            end
            app.PlotData; 
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Covid-19 Pandemic Data')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateListBoxLabel = uilabel(app.UIFigure);
            app.StateListBoxLabel.Text = 'State';
            app.StateListBox = uilistbox(app.UIFigure);
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.AverageofDaysSliderLabel = uilabel(app.UIFigure);
            app.AverageofDaysSliderLabel.Text = 'Average # of Days';
            app.AverageofDaysSlider = uislider(app.UIFigure);
            app.AverageofDaysSlider.Limits = [1 15];
            app.AverageofDaysSlider.ValueChangingFcn = createCallbackFcn(app, @AverageofDaysSliderValueChanging, true);
            app.AverageofDaysSlider.Value = 1;
            app.DatatoPlotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoPlotButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Covid_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end